classdef HAR < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        PredictActivityEditField       matlab.ui.control.EditField
        PredictActivityEditFieldLabel  matlab.ui.control.Label
        Button                         matlab.ui.control.Button
        MQTTBrokerEditField            matlab.ui.control.EditField
        MQTTBrokerEditFieldLabel       matlab.ui.control.Label
        PortEditField                  matlab.ui.control.NumericEditField
        PortEditFieldLabel             matlab.ui.control.Label
        CLientIDEditField              matlab.ui.control.EditField
        CLientIDEditFieldLabel         matlab.ui.control.Label
        TopicEditField                 matlab.ui.control.EditField
        TopicEditFieldLabel            matlab.ui.control.Label
        RightPanel                     matlab.ui.container.Panel
        HearBeatEditField              matlab.ui.control.NumericEditField
        HearBeatEditFieldLabel         matlab.ui.control.Label
        GzEditField                    matlab.ui.control.NumericEditField
        GzEditFieldLabel               matlab.ui.control.Label
        GyEditField                    matlab.ui.control.NumericEditField
        GyEditFieldLabel               matlab.ui.control.Label
        GxEditField                    matlab.ui.control.NumericEditField
        GxEditFieldLabel               matlab.ui.control.Label
        AzEditField                    matlab.ui.control.NumericEditField
        AzEditFieldLabel               matlab.ui.control.Label
        AyEditField                    matlab.ui.control.NumericEditField
        AyEditFieldLabel               matlab.ui.control.Label
        AxEditField                    matlab.ui.control.NumericEditField
        AxEditFieldLabel               matlab.ui.control.Label
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: PortEditField
        function PortEditFieldValueChanged(app, event)
            mqttPort = app.PortEditField.Value;
            
        end

        % Value changed function: TopicEditField
        function TopicEditFieldValueChanged(app, event)
            mqttTopic = app.TopicEditField.Value;
            
        end

        % Value changed function: CLientIDEditField
        function CLientIDEditFieldValueChanged(app, event)
            mqttClientID = app.CLientIDEditField.Value;
            
        end

        % Callback function
        function ClientInfoTextAreaValueChanged(app, event)
           app.ClientInfoTextAreaLabel.Text = event;
        end

        % Value changed function: MQTTBrokerEditField
        function MQTTBrokerEditFieldValueChanged(app, event)
            mqttBrokerURL = app.MQTTBrokerEditField.Value;

        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
myMQTT = mqttclient('tcp://mqtt.eclipseprojects.io', Port = app.PortEditField.Value, ClientID=app.CLientIDEditField.Value);
%Topic_sub = mqttTopic;
%Data = subscribe(myMQTT,app.TopicEditField.Value);

ax = 0;
ay= 0;
az = 0;
gx = 0;
gy = 0;
gz = 0;
global temp;
temp = 0;
global buff;
buff = zeros(1,60,6);
params = importONNXFunction("model_onnx2.onnx","modelHAR");
predicted_label = ["running","walking","sitting","hiking","swimming","swimming2"];
hfig = figure;clf
hax = axes(hfig);
app.UIAxesButtonDown.anim_lineaX = animatedline('Parent',hax,'Color','red','MaximumNumPoints',60);
app.UIAxesButtonDown.anim_lineaY = animatedline('Parent',hax,'Color','green','MaximumNumPoints',60);
app.UIAxesButtonDown.anim_lineaZ = animatedline('Parent',hax,'Color','blue','MaximumNumPoints',60);
legend('Ax', 'Ay', 'Az');
gfig = figure;clf
hgy = axes(gfig);
app.UIAxes2ButtonDown.anim_linegX = animatedline('Parent',hgy,'Color','red','MaximumNumPoints',60);
app.UIAxes2ButtonDown.anim_linegY = animatedline('Parent',hgy,'Color','green','MaximumNumPoints',60);
app.UIAxes2ButtonDown.anim_linegZ = animatedline('Parent',hgy,'Color','blue','MaximumNumPoints',60);
legend('Gx', 'Gy', 'Gz');
Data = subscribe(myMQTT, app.TopicEditField.Value, 'QualityOfService', 1, 'Callback', @(src, msg) handleMessage(src, msg, app.UIAxesButtonDown.anim_lineaX, app.UIAxesButtonDown.anim_lineaY, app.UIAxesButtonDown.anim_lineaZ, app.UIAxes2ButtonDown.anim_linegX, app.UIAxes2ButtonDown.anim_linegY, app.UIAxes2ButtonDown.anim_linegZ));
%Data = subscribe(myMQTT,app.TopicEditField.Value, QualityOfService = 1, Callback = @(src, msg) handleMessage(src, msg, app.UIAxesButtonDown.anim_lineaX, app.UIAxesButtonDown.anim_lineaY, app.UIAxesButtonDown.anim_lineaZ, app.UIAxes2ButtonDown.anim_linegX, app.UIAxes2ButtonDown.anim_linegY, app.UIAxes2ButtonDown.anim_linegZ));
function handleMessage(~, message, lineaX, lineaY, lineaZ, linegX, linegY, linegZ)
    try
        %tic;
        op = jsondecode(message);
        disp(op);
        ax = op.ax;
        ay = op.ay;
        az = op.az;
        gx = op.gx;
        gy = op.gy;
        gz = op.gz;
        %global temp;
        t = op.Time;
        if t <= temp
            disp('Return');
            return
        end
                      
        addpoints(lineaX, t, ax);
        addpoints(lineaY, t, ay);
        addpoints(lineaZ, t, az);
        addpoints(linegX, t, gx);
        addpoints(linegY, t, gy);
        addpoints(linegZ, t, gz);
        temp = t;
        %global buff;
        %global params;
        %global reuse_buff;
        nextIndex = find(buff(1,:,1) == 0, 1);
        buff(1, nextIndex, :) =  [ax, ay, az, gx, gy, gz];
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        data_2d = reshape(buff, 100, 6);
        
        segment_length = 60;
        overlap_length = 40;
        
        step_size = segment_length - overlap_length;
        
        num_buffers = ceil((size(data_2d, 1) - overlap_length) / step_size);
        
        buffered_data = zeros(num_buffers, segment_length, size(data_2d, 2));
        
        for k = 1:num_buffers
            start_idx = (k - 1) * step_size + 1;
            
            end_idx = start_idx + segment_length - 1;
            
            if end_idx <= size(data_2d, 1)
                buffered_data(k, :, :) = data_2d(start_idx:end_idx, :);
                
            else
                % Handle cases where the buffer would exceed data length
                buffered_data(k, 1:(size(data_2d, 1) - start_idx + 1), :) = data_2d(start_idx:end, :);
                
            end
        end
        buffered_data_3d = reshape(buffered_data, num_buffers, segment_length, size(buff, 3));
 
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        if nextIndex >= 100
           
           [dense_1, ] = modelHAR3(buffered_data_3d(3,1:60,:), params);
           [~, predicted_class] = max(dense_1);
           predicted_label = ["jogging","sitting","stairs","standing","walking"];
           %disp(["Predict Activity: " predicted_label(predicted_class)])
           
           reuse_buff = buffered_data_3d(3,1:60,:);
           buff = zeros(1, 100, 6);
        elseif nextIndex >= 80
           
           [dense_1, ] = modelHAR3(buffered_data_3d(2,1:60,:), params);
           [~, predicted_class] = max(dense_1);
           predicted_label = ["jogging","sitting","stairs","standing","walking"];
           %disp(["Predict Activity: " predicted_label(predicted_class)])
           
        elseif nextIndex >= 60
          
           [dense_1, ] = modelHAR3(buffered_data_3d(1,1:60,:), params);
           [~, predicted_class] = max(dense_1);
           predicted_label = ["jogging","sitting","stairs","standing","walking"];
           %disp(["Predict Activity: " predicted_label(predicted_class)])
         
        elseif nextIndex >= 0
           
           [dense_1, ] = modelHAR3(reuse_buff, params);
           [~, predicted_class] = max(dense_1);
           predicted_label = ["jogging","sitting","stairs","standing","walking"];
           %disp(["Predict Activity: " predicted_label(predicted_class)])
           
        end

    catch ME
        disp(['Lỗi khi xử lý tin nhắn: ' ME.message]);
    end
end


        end

        % Value changed function: PredictActivityEditField
        function PredictActivityEditFieldValueChanged(app, event)
            value = app.PredictActivityEditField.Value;
            
        end

        % Callback function
        function EditFieldValueChanged(app, event)
            %value = app.AxEditField.Value;
            
        end

        % Button down function: UIAxes2
        function UIAxes2ButtonDown(app, event)
            
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {524, 524};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {256, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TopicEditFieldLabel = uilabel(app.LeftPanel);
            app.TopicEditFieldLabel.Text = 'Topic';
            app.TopicEditField = uieditfield(app.LeftPanel, 'text');
            app.TopicEditField.ValueChangedFcn = createCallbackFcn(app, @TopicEditFieldValueChanged, true);
            app.CLientIDEditFieldLabel = uilabel(app.LeftPanel);
            app.CLientIDEditFieldLabel.Text = 'CLientID';
            app.CLientIDEditField = uieditfield(app.LeftPanel, 'text');
            app.CLientIDEditField.ValueChangedFcn = createCallbackFcn(app, @CLientIDEditFieldValueChanged, true);
            app.PortEditFieldLabel = uilabel(app.LeftPanel);
            app.PortEditFieldLabel.Text = 'Port';
            app.PortEditField = uieditfield(app.LeftPanel, 'numeric');
            app.PortEditField.ValueChangedFcn = createCallbackFcn(app, @PortEditFieldValueChanged, true);
            app.MQTTBrokerEditFieldLabel = uilabel(app.LeftPanel);
            app.MQTTBrokerEditFieldLabel.Text = 'MQTT Broker';
            app.MQTTBrokerEditField = uieditfield(app.LeftPanel, 'text');
            app.MQTTBrokerEditField.ValueChangedFcn = createCallbackFcn(app, @MQTTBrokerEditFieldValueChanged, true);
            app.Button = uibutton(app.LeftPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.PredictActivityEditFieldLabel = uilabel(app.LeftPanel);
            app.PredictActivityEditFieldLabel.Text = 'Predict Activity';
            app.PredictActivityEditField = uieditfield(app.LeftPanel, 'text');
            app.PredictActivityEditField.ValueChangedFcn = createCallbackFcn(app, @PredictActivityEditFieldValueChanged, true);
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Accelerometer')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'Gyroscope')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.ButtonDownFcn = createCallbackFcn(app, @UIAxes2ButtonDown, true);
            app.AxEditFieldLabel = uilabel(app.RightPanel);
            app.AxEditFieldLabel.Text = 'Ax';
            app.AxEditField = uieditfield(app.RightPanel, 'numeric');
            app.AyEditFieldLabel = uilabel(app.RightPanel);
            app.AyEditFieldLabel.Text = 'Ay';
            app.AyEditField = uieditfield(app.RightPanel, 'numeric');
            app.AzEditFieldLabel = uilabel(app.RightPanel);
            app.AzEditFieldLabel.Text = 'Az';
            app.AzEditField = uieditfield(app.RightPanel, 'numeric');
            app.GxEditFieldLabel = uilabel(app.RightPanel);
            app.GxEditFieldLabel.Text = 'Gx';
            app.GxEditField = uieditfield(app.RightPanel, 'numeric');
            app.GyEditFieldLabel = uilabel(app.RightPanel);
            app.GyEditFieldLabel.Text = 'Gy';
            app.GyEditField = uieditfield(app.RightPanel, 'numeric');
            app.GzEditFieldLabel = uilabel(app.RightPanel);
            app.GzEditFieldLabel.Text = 'Gz';
            app.GzEditField = uieditfield(app.RightPanel, 'numeric');
            app.HearBeatEditFieldLabel = uilabel(app.RightPanel);
            app.HearBeatEditFieldLabel.Text = 'Hear Beat';
            app.HearBeatEditField = uieditfield(app.RightPanel, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HAR

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end